classdef BlueWhaleDashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        WhaleSelectionPanel           matlab.ui.container.Panel
        WhaleIDDropDown               matlab.ui.control.DropDown
        WhaleIDDropDownLabel          matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        AnalysisOptionsPanel          matlab.ui.container.Panel
        TimeUnitsDropDown             matlab.ui.control.DropDown
        TimeUnitsDropDownLabel        matlab.ui.control.Label
        DistanceUnitsDropDown         matlab.ui.control.DropDown
        DistanceUnitsDropDownLabel    matlab.ui.control.Label
        LockGeographicLimitsCheckBox  matlab.ui.control.CheckBox
        AnimateTravelPathCheckBox     matlab.ui.control.CheckBox
        VisualizationOptionsPanel     matlab.ui.container.Panel
        MarkerColorDropDown           matlab.ui.control.DropDown
        MarkerColorDropDownLabel      matlab.ui.control.Label
        LineColorDropDown             matlab.ui.control.DropDown
        LineColorDropDownLabel        matlab.ui.control.Label
        MarkerSizeSlider              matlab.ui.control.Slider
        MarkerSizeSliderLabel         matlab.ui.control.Label
        MarkerDropDown                matlab.ui.control.DropDown
        MarkerDropDownLabel           matlab.ui.control.Label
        LineWidthSlider               matlab.ui.control.Slider
        LineWidthSliderLabel          matlab.ui.control.Label
        LineStyleDropDown             matlab.ui.control.DropDown
        LineStyleDropDownLabel        matlab.ui.control.Label
    end

    % App to explore and analyze blue whale data 
    %
    % Copyright 2021 The MathWorks, Inc.

    properties (Access = public)
        allWhales % Table with full dataset
        MapAxes % Geographic axes for plotting 
    end   


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load data
            load cleanWhaleData whales
            app.allWhales = whales;

            % Get list of whales
            whalesSummary = groupsummary(app.allWhales,"ID","range","latitude");
            whalesSummary = sortrows(whalesSummary,'range_latitude','descend');
            app.WhaleIDDropDown.Items = whalesSummary.ID;

            % Create Geographic axes
           app.MapAxes = geoaxes("Parent",app.UIFigure,"Units","pixels", ...
                "Position",[430,55,450,350]);           
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Get single whale data
            rowsToKeep = app.allWhales.ID==app.WhaleIDDropDown.Value;
            whale = app.allWhales(rowsToKeep,:);    
            
            % Get variables to match the live script versions (so code doesn't need editing)            
            doAnimation = app.AnimateTravelPathCheckBox.Value;
            setGeoLimits = app.LockGeographicLimitsCheckBox.Value;
            distanceUnits = string(app.DistanceUnitsDropDown.Value);
            timeUnits = string(app.TimeUnitsDropDown.Value);
            pathMarker = app.MarkerDropDown.Value;
            markerColor = app.MarkerColorDropDown.Value;
            pathLineWidth = app.LineWidthSlider.Value;
            pathColor = app.LineColorDropDown.Value;
            pathLineStyle = app.LineStyleDropDown.Value;
            pathMarkerSize = app.MarkerSizeSlider.Value;
            id = app.WhaleIDDropDown.Value;  

            % Get basic stats from table
            numPoints = height(whale);
            timeBounds = whale.timestamp([1 end]);
            timeBounds.Format = "yyyy-MM-dd HH:mm";
            latRange = range(whale.latitude);
            lonRange = range(whale.longitude);
            
            % Use custom function to calculate speed
            [whale,kmPerDay] = whaleSpeed(whale);
            
            % Make some new variables to hold values with converted units
            avgSpeed = kmPerDay;
            whale.elapsedDistance = whale.distance;
            whale.elapsedTime = whale.elapsedDays;
            
            % Change units
            if distanceUnits == "mi"
                avgSpeed = avgSpeed / 1.61;
                whale.elapsedDistance = whale.elapsedDistance / 1.61;
            end
            if timeUnits == "year"
                avgSpeed = avgSpeed * 365;
                whale.elapsedTime = whale.elapsedTime / 365;
            elseif timeUnits == "hour"
                avgSpeed = avgSpeed / 24;
                whale.elapsedTime = whale.elapsedTime * 24;
            end
            
            % Get values for total time and distance
            totalTime = whale.elapsedTime(end);
            totalDistance = whale.elapsedDistance(end);
            
            % Assemble variables for stats table
            speedUnits = distanceUnits + "/" + timeUnits;
            rowNames = ["Number of Points","Time Start","Time End","Latitude Range (deg)", ...
                "Longitude Range (deg)","Elapsed time (" + timeUnits + "s)", ...
                "Distance Travelled (" + distanceUnits + ")","Speed (" + speedUnits + ")"];            
            statsValue = {numPoints; timeBounds(1); timeBounds(2); latRange; lonRange; ...
                totalTime; totalDistance; avgSpeed};
            
            % Display table to user
            app.UITable.Data = table(statsValue);
            app.UITable.RowName = rowNames;
                        
            % Update map
            if doAnimation
                for ii = 1:height(whale)            
                    % Add line and markers
                    geoplot(app.MapAxes,whale.latitude(ii),whale.longitude(ii),"Marker",pathMarker, ...
                        "MarkerEdgeColor",markerColor,"LineStyle","none", ...
                        "LineWidth",pathLineWidth,"MarkerSize",pathMarkerSize);
                    hold(app.MapAxes,"on")
                    geoplot(app.MapAxes,whale.latitude(1:ii),whale.longitude(1:ii),"LineStyle",pathLineStyle, ...
                        "LineWidth",pathLineWidth,"Color",pathColor)
                    hold(app.MapAxes,"off")
            
                    % Set axes limits
                    if setGeoLimits
                        geolimits(app.MapAxes,[0 55],[-150 -90])
                    end
                
                    % Add title with info
                    timeUnits{1}(1) = upper(timeUnits{1}(1));
                    timeValue = whale.elapsedTime(ii);
                    if timeUnits == "Day" || timeUnits == "Hour"
                        timeValue = round(timeValue);
                    end
                    titleStr = timeUnits + " = " + timeValue + ...
                        ", Distance = " + round(whale.elapsedDistance(ii)) + " " + distanceUnits + ...
                        ", Avg Speed = " + avgSpeed +  " " + speedUnits;
                    title(app.MapAxes,titleStr)
            
                    % Update plot (use faster option for whales with many points)
                    if height(whale) >= 100
                        drawnow limitrate
                    else
                        drawnow
                    end
                end
            else
                % Create static plot
                geoplot(app.MapAxes,whale.latitude,whale.longitude,"LineStyle",pathLineStyle, ...
                    "Marker",pathMarker,"LineWidth",pathLineWidth, ...
                    "Color",pathColor,"MarkerSize",pathMarkerSize, ...
                    "MarkerEdgeColor",markerColor);
                title(app.MapAxes,id)
                if setGeoLimits
                    geolimits(app.MapAxes,[0 55],[-150 -90])
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VisualizationOptionsPanel = uipanel(app.UIFigure);
            app.VisualizationOptionsPanel.Title = 'Visualization Options';
            app.LineStyleDropDownLabel = uilabel(app.VisualizationOptionsPanel);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.LineStyleDropDown = uidropdown(app.VisualizationOptionsPanel);
            app.LineStyleDropDown.Items = {'Solid line', 'Dashed Line', 'Dotted Line', 'None'};
            app.LineStyleDropDown.ItemsData = {'-', '--', ':', 'none'};
            app.LineStyleDropDown.Value = '-';
            app.LineWidthSliderLabel = uilabel(app.VisualizationOptionsPanel);
            app.LineWidthSliderLabel.Text = 'Line Width';
            app.LineWidthSlider = uislider(app.VisualizationOptionsPanel);
            app.LineWidthSlider.Limits = [0 5];
            app.LineWidthSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.LineWidthSlider.Value = 1;
            app.MarkerDropDownLabel = uilabel(app.VisualizationOptionsPanel);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.VisualizationOptionsPanel);
            app.MarkerDropDown.Items = {'None', 'Circle', 'Square', 'Triangle'};
            app.MarkerDropDown.ItemsData = {'None', 'o', 's', '^'};
            app.MarkerDropDown.Value = 'None';
            app.MarkerSizeSliderLabel = uilabel(app.VisualizationOptionsPanel);
            app.MarkerSizeSliderLabel.Text = 'Marker Size';
            app.MarkerSizeSlider = uislider(app.VisualizationOptionsPanel);
            app.MarkerSizeSlider.Limits = [0 20];
            app.MarkerSizeSlider.MajorTicks = [0 5 10 15 20];
            app.MarkerSizeSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.MarkerSizeSlider.Value = 5;
            app.LineColorDropDownLabel = uilabel(app.VisualizationOptionsPanel);
            app.LineColorDropDownLabel.Text = 'Line Color';
            app.LineColorDropDown = uidropdown(app.VisualizationOptionsPanel);
            app.LineColorDropDown.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Yellow', 'Magenta', 'Black'};
            app.LineColorDropDown.ItemsData = {'r', 'g', 'b', 'c', 'y', 'm', 'k'};
            app.LineColorDropDown.Value = 'b';
            app.MarkerColorDropDownLabel = uilabel(app.VisualizationOptionsPanel);
            app.MarkerColorDropDownLabel.Text = 'Marker Color';
            app.MarkerColorDropDown = uidropdown(app.VisualizationOptionsPanel);
            app.MarkerColorDropDown.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Yellow', 'Magenta', 'Black'};
            app.MarkerColorDropDown.ItemsData = {'r', 'g', 'b', 'c', 'y', 'm', 'k'};
            app.MarkerColorDropDown.Value = 'r';
            app.AnalysisOptionsPanel = uipanel(app.UIFigure);
            app.AnalysisOptionsPanel.Title = 'Analysis Options';
            app.AnimateTravelPathCheckBox = uicheckbox(app.AnalysisOptionsPanel);
            app.AnimateTravelPathCheckBox.Text = 'Animate Travel Path';
            app.LockGeographicLimitsCheckBox = uicheckbox(app.AnalysisOptionsPanel);
            app.LockGeographicLimitsCheckBox.Text = 'Lock Geographic Limits';
            app.DistanceUnitsDropDownLabel = uilabel(app.AnalysisOptionsPanel);
            app.DistanceUnitsDropDownLabel.Text = 'Distance Units';
            app.DistanceUnitsDropDown = uidropdown(app.AnalysisOptionsPanel);
            app.DistanceUnitsDropDown.Items = {'kilometers', 'miles'};
            app.DistanceUnitsDropDown.ItemsData = {'km', 'mi'};
            app.DistanceUnitsDropDown.Value = 'km';
            app.TimeUnitsDropDownLabel = uilabel(app.AnalysisOptionsPanel);
            app.TimeUnitsDropDownLabel.Text = 'Time Units';
            app.TimeUnitsDropDown = uidropdown(app.AnalysisOptionsPanel);
            app.TimeUnitsDropDown.Items = {'years', 'days', 'hours'};
            app.TimeUnitsDropDown.ItemsData = {'year', 'day', 'hour'};
            app.TimeUnitsDropDown.Value = 'year';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.WhaleSelectionPanel = uipanel(app.UIFigure);
            app.WhaleSelectionPanel.Title = 'Whale Selection';
            app.WhaleIDDropDownLabel = uilabel(app.WhaleSelectionPanel);
            app.WhaleIDDropDownLabel.Text = 'Whale ID';
            app.WhaleIDDropDown = uidropdown(app.WhaleSelectionPanel);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Value'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BlueWhaleDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end