classdef EigenSquare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        OptionsPanel       matlab.ui.container.Panel
        YmodeSpinner       matlab.ui.control.Spinner
        YmodeSpinnerLabel  matlab.ui.control.Label
        SpeedSlider        matlab.ui.control.Slider
        SpeedSliderLabel   matlab.ui.control.Label
        XmodeSpinner       matlab.ui.control.Spinner
        XmodeSpinnerLabel  matlab.ui.control.Label
        WavesCheckBox      matlab.ui.control.CheckBox
        RunButton          matlab.ui.control.StateButton
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        V % Eigenfunctions 
        x % X-coordinates
        y % Y-coordinates
        mu % sqrt(Eigenvalues)
        a % amplitudes
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App to explore eigenfunctions
            %
            % Copyright 2021 The MathWorks, Inc.
            % This app was adapted from one of the examples in 
            % Cleve_Lab (waves.m). See the link below for source code.
            % https://www.mathworks.com/matlabcentral/fileexchange/59085-cleve_lab

            % Set up the V matrices with the eigenfunctions
            m = 11;   % Determines number of grid points  
            app.x = (0:2*m)/(2*m)*pi;
            app.y = app.x';
            nModes = 4;
            lambda = zeros(nModes^2,1);
            app.V = zeros(length(app.y),length(app.x),nModes^2);
            k = 0;
            for i = 1:nModes
                for j = 1:nModes
                    k = k+1;
                    lambda(k) = i^2 + j^2;
                    app.V(:,:,k) = sin(i*app.y)*sin(j*app.x);
                end
            end
            app.mu = sqrt(lambda(:));
            app.a = 1.25./(1:nModes^2);

            % Set up axes
            surf(app.UIAxes,app.x,app.y,zeros(size(app.V(:,:,1))));
            axis(app.UIAxes,[0 pi 0 pi -2 2])
            axis(app.UIAxes,'off')                  
            caxis(app.UIAxes,[-1.5 1]);
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            % Start at time=0 and increment to show wave behavior
            t = 0; 
            nModes = sqrt(size(app.V,3)); 
            while app.RunButton.Value

                % Increment time based on speed slide
                t = t + 0.04 * 2^app.SpeedSlider.Value;                

                % Pick index based on Mode spinner values
                k = (app.YmodeSpinner.Value-1)*nModes + app.XmodeSpinner.Value;

                % Show animation based on checkbox
                if app.WavesCheckBox.Value
                    U = zeros(size(app.V(:,:,1)));
                    for ki = 1:k
                        U = U + app.a(ki)*sin(app.mu(ki)*t)*app.V(:,:,ki);
                    end
                else
                    U = 1.5*sin(app.mu(k)*t)*app.V(:,:,k);
                end   

                % Update plot
                surf(app.UIAxes,app.x,app.y,U);                         
                drawnow 

                % End the animation if time-out detected
                if t > 20 
                    app.RunButton.Value = false;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.WavesCheckBox = uicheckbox(app.OptionsPanel);
            app.WavesCheckBox.Text = 'Waves';
            app.XmodeSpinnerLabel = uilabel(app.OptionsPanel);
            app.XmodeSpinnerLabel.Text = 'X mode';
            app.XmodeSpinner = uispinner(app.OptionsPanel);
            app.XmodeSpinner.Limits = [1 4];
            app.XmodeSpinner.RoundFractionalValues = 'on';
            app.XmodeSpinner.Value = 1;
            app.SpeedSliderLabel = uilabel(app.OptionsPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.OptionsPanel);
            app.SpeedSlider.Limits = [-3 3];
            app.SpeedSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.SpeedSlider.MajorTickLabels = {'1/8', '1/4', '1/2', '1', '2', '4', '8'};
            app.YmodeSpinnerLabel = uilabel(app.OptionsPanel);
            app.YmodeSpinnerLabel.Text = 'Y mode';
            app.YmodeSpinner = uispinner(app.OptionsPanel);
            app.YmodeSpinner.Limits = [1 4];
            app.YmodeSpinner.RoundFractionalValues = 'on';
            app.YmodeSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EigenSquare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end